classdef ECE4950GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Time                           matlab.ui.control.Label
        TimeLabel                      matlab.ui.control.Label
        BoardImageLabel                matlab.ui.control.Label
        RemoveHoleMarkersLabel         matlab.ui.control.Label
        PlaceHoleMarkersandBallLabel   matlab.ui.control.Label
        CaptureImageofBoardButton      matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        TheSelectedHoleisLabel         matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ChooseaTargetColorButtonGroup  matlab.ui.container.ButtonGroup
        ChooseButton                   matlab.ui.control.RadioButton
        RedButton                      matlab.ui.control.RadioButton
        GreyButton                     matlab.ui.control.RadioButton
        BlueButton                     matlab.ui.control.RadioButton
        StartButton                    matlab.ui.control.Button
    end

    properties (Access = public)
        myTimer = 0;
    end


%     methods (Access = public)
%         
%         function startupFcn = func(app)
%             app.myTimer = timer('Period', 0.1, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
%             app.myTimer.TimerFcn = @app.TimerCallback;
%         end
%     end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function TimerCallback(app)

        end

        % Code that executes after component creation
        function startupFcn(app, a)
            app.myTimer = timer('Period', 0.1, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.myTimer.TimerFcn = @app.TimerCallback1;
        end

        % Button pushed function: StartButton
        function startButton(app, event)
            global selectedButton;
            app.Label.Text = selectedButton.Text;
            start(app.myTimer);
            run("Project4.m");
            stop(app.myTimer);
            %run("Project4.m/Testing()")
            %run("Project4.m");
        end

        % Selection changed function: ChooseaTargetColorButtonGroup
        function colorChanged(app, event)
            global selectedButton;
            selectedButton = app.ChooseaTargetColorButtonGroup.SelectedObject;
            if selectedButton.Text == "Blue" || selectedButton.Text == "Grey" || selectedButton.Text == "Red"
                app.StartButton.BackgroundColor = [98,166,10]/255;
                app.Image.ImageSource = "background.png";
            else
                app.StartButton.BackgroundColor = 'r';
            end
            
            %disp(selectedButton);
            
        end

        % Button pushed function: CaptureImageofBoardButton
        function imageCaptureButton(app, event)

            cam_list = webcamlist;
            cam_name = cam_list{1};
            cam = webcam(cam_name);
            pause(2);
            rgb = snapshot(cam);
            
            
            imwrite(rgb, "background.png")
            %
            %imageCapture();
        end

        % Window button down function: UIFigure
        function TimerCallback1(app, event)
            %app.Time(app, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @TimerCallback1, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @TimerCallback, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startButton, true);
            app.StartButton.Text = '5. Start';
            app.ChooseaTargetColorButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseaTargetColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @colorChanged, true);
            app.ChooseaTargetColorButtonGroup.Title = '4. Choose a Target Color';
            app.BlueButton = uiradiobutton(app.ChooseaTargetColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.GreyButton = uiradiobutton(app.ChooseaTargetColorButtonGroup);
            app.GreyButton.Text = 'Grey';
            app.RedButton = uiradiobutton(app.ChooseaTargetColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.ChooseButton = uiradiobutton(app.ChooseaTargetColorButtonGroup);
            app.ChooseButton.Enable = 'off';
            app.ChooseButton.Visible = 'off';
            app.ChooseButton.Text = 'Choose';
            app.ChooseButton.Value = true;
            app.Image = uiimage(app.UIFigure);
            app.TheSelectedHoleisLabel = uilabel(app.UIFigure);
            app.TheSelectedHoleisLabel.Text = 'The Selected Hole is:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CaptureImageofBoardButton = uibutton(app.UIFigure, 'push');
            app.CaptureImageofBoardButton.ButtonPushedFcn = createCallbackFcn(app, @imageCaptureButton, true);
            app.CaptureImageofBoardButton.Text = '2. Capture Image of Board';
            app.PlaceHoleMarkersandBallLabel = uilabel(app.UIFigure);
            app.PlaceHoleMarkersandBallLabel.Text = '1. Place Hole Markers and Ball';
            app.RemoveHoleMarkersLabel = uilabel(app.UIFigure);
            app.RemoveHoleMarkersLabel.Text = {'3. Remove Hole Markers'; ''};
            app.BoardImageLabel = uilabel(app.UIFigure);
            app.BoardImageLabel.Text = 'Board Image';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = {'Time:'; ''};
            app.Time = uilabel(app.UIFigure);
            app.Time.Text = '0.000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECE4950GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end